classdef kb_terminal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        saveButton          matlab.ui.control.Button
        plotButton          matlab.ui.control.Button
        exportButton        matlab.ui.control.Button
        TimeLapseLabel      matlab.ui.control.Label
        KeyOutputButton     matlab.ui.control.Button
        KeyLabel            matlab.ui.control.Label
        TimeLabel           matlab.ui.control.Label
        CounterResetButton  matlab.ui.control.Button
        CounterLabel        matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        Switch              matlab.ui.control.Switch
    end

    
    properties (Access = public)
        Property % Description
        
        KbObj=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.KbObj.counter=0;
            app.KbObj.time=0;
            app.KbObj.key=[];
            app.KbObj.flag_start=0;


            app.Lamp.Color='R';


            app.CounterLabel.Text='0000';
            app.TimeLabel.Text='';
            app.KeyLabel.Text='';

            app.TimeLapseLabel.Text='';


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % keyboard setup by Psychtoolbox
            %app.KbObj.deviceIndex = [];
            app.KbObj.deviceIndex = -1;


            % Enable unified mode of KbName, so KbName accepts identical key names on
            % all operating systems:
            KbName('UnifyKeyNames');

            % Prevent spilling of keystrokes into console:
            ListenChar(-1);


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if(strcmp(value,'On'))
                try

                    app.Lamp.Color='y';

                    escapeKey = KbName('ESCAPE');
                    startSecs = GetSecs;

                    KbQueueCreate(app.KbObj.deviceIndex);
                    KbQueueStart(app.KbObj.deviceIndex);

                    flag_cont=1;
                    while(flag_cont)
                        [ pressed, firstPress]=KbQueueCheck(app.KbObj.deviceIndex);
                        timeSecs = firstPress(find(firstPress)); %#ok<FNDSB>
                        if pressed
                            % Again, fprintf will give an error if multiple keys have been pressed
                            fprintf('"%s" typed at time %.3f seconds\n', KbName(min(find(firstPress))), timeSecs - startSecs);

                            app.Lamp.Color='g';
                            app.KbObj.counter=app.KbObj.counter+1;
                            app.CounterLabel.Text=sprintf('%04d',app.KbObj.counter);

                            app.KbObj.key{end+1}=KbName(min(find(firstPress)));
                            app.KeyLabel.Text=KbName(min(find(firstPress)));

                            app.KbObj.time(end+1)=timeSecs - startSecs;
                            app.TimeLabel.Text=sprintf('%2.2f', timeSecs - startSecs);

                            if firstPress(escapeKey)
                                flag_cont=0;
                            end

                        end

                        if((GetSecs-app.KbObj.time(end)-startSecs)>.05)
                            app.Lamp.Color='y';
                        end;

                        app.TimeLapseLabel.Text=sprintf('%2.2f', GetSecs - startSecs);

                        if(strcmp(app.Switch.Value,'Off'))
                            flag_cont=0;
                        end;

                        pause(0.005);

                    end

                    KbQueueRelease(app.KbObj.deviceIndex);


                catch

                end;
            elseif(strcmp(value,'Off'))
                try

                    app.Lamp.Color='r';

                    KbQueueRelease(app.KbObj.deviceIndex);
                    sca;

                    app.CounterLabel.Text='0000';
                    app.TimeLabel.Text='';
                    app.KeyLabel.Text='';

                    app.TimeLapseLabel.Text='';

                catch

                end
            end;
        end

        % Button pushed function: CounterResetButton
        function CounterResetButtonPushed(app, event)
                        
            app.KbObj.counter=0;

            app.CounterLabel.Text='0000';
        end

        % Button pushed function: KeyOutputButton
        function KeyOutputButtonPushed(app, event)
            
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
        
           assignin('base','KbObj',app.KbObj);

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)

            tstr=datestr(datetime('now'),'mmddyy_HHMMss');
            fn=sprintf('kb_history_%s.mat',tstr);
            fprintf('saving [%s]...',fn);
            KbObj=app.KbObj;
            save(fn,'KbObj');

            fprintf('done!\n');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.CounterLabel = uilabel(app.Panel);
            app.CounterLabel.Text = '0000';
            app.CounterResetButton = uibutton(app.Panel, 'push');
            app.CounterResetButton.ButtonPushedFcn = createCallbackFcn(app, @CounterResetButtonPushed, true);
            app.CounterResetButton.Text = 'R';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = '';
            app.KeyLabel = uilabel(app.Panel);
            app.KeyLabel.Text = '';
            app.KeyOutputButton = uibutton(app.Panel, 'push');
            app.KeyOutputButton.ButtonPushedFcn = createCallbackFcn(app, @KeyOutputButtonPushed, true);
            app.KeyOutputButton.Enable = 'off';
            app.KeyOutputButton.Text = 'K';
            app.TimeLapseLabel = uilabel(app.Panel);
            app.TimeLapseLabel.Text = '';
            app.exportButton = uibutton(app.Panel, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.plotButton = uibutton(app.Panel, 'push');
            app.plotButton.Enable = 'off';
            app.plotButton.Text = 'plot';
            app.saveButton = uibutton(app.Panel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kb_terminal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end